classdef bloodpressureapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ButtonGroup               matlab.ui.container.ButtonGroup
        MaleButton                matlab.ui.control.RadioButton
        FemaleButton              matlab.ui.control.RadioButton
        BloodPressureSwitchLabel  matlab.ui.control.Label
        BloodPressureSwitch       matlab.ui.control.Switch
        PlotButton                matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            load patients.mat
            
            bp = string(app.BloodPressureSwitch.Value);
            
            if bp == "Systolic"
                if app.FemaleButton.Value == true
                    x = Weight(Gender == "Female");
                    y = Systolic(Gender == "Female");
                
                elseif app.MaleButton.Value == true
                    x = Weight(Gender == "Male");
                    y = Systolic(Gender == "Male");
                    
                end
            
            elseif bp == "Diastolic"
                if app.FemaleButton.Value == true
                    x = Weight(Gender == "Female");
                    y = Diastolic(Gender == "Female");
                
                elseif app.MaleButton.Value == true
                    x = Weight(Gender == "Male");
                    y = Diastolic(Gender == "Male");
                    
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.MaleButton = uiradiobutton(app.ButtonGroup);
            app.MaleButton.Text = 'Male';
            app.MaleButton.Value = true;
            app.FemaleButton = uiradiobutton(app.ButtonGroup);
            app.FemaleButton.Text = 'Female';
            app.BloodPressureSwitchLabel = uilabel(app.UIFigure);
            app.BloodPressureSwitchLabel.Text = 'BloodPressure';
            app.BloodPressureSwitch = uiswitch(app.UIFigure, 'slider');
            app.BloodPressureSwitch.Items = {'Systolic', 'Diastolic'};
            app.BloodPressureSwitch.Value = 'Systolic';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Weight Vs Blood Pressure')
            xlabel(app.UIAxes, 'Weight')
            ylabel(app.UIAxes, 'Blood Pressure')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bloodpressureapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end